classdef deindentifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputDirectoryButton         matlab.ui.control.Button
        AnonymiseFractiondataButton   matlab.ui.control.Button
        FolderinwhichthedatahavebeencopiedLabel  matlab.ui.control.Label
        NametoreplaceEditFieldLabel   matlab.ui.control.Label
        NametoreplaceEditField        matlab.ui.control.EditField
        PatientnameEditFieldLabel     matlab.ui.control.Label
        PatientnameEditField          matlab.ui.control.EditField
        FormatFirstnameLastnameLabel  matlab.ui.control.Label
        FormatLARK_WM_PAT1Label       matlab.ui.control.Label
        AnonymiseCBCTdataButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function InputDirectoryButtonPushed(app, event)
            global KIM
            originalPlanPath = uigetdir('C:\');     
            KIM.originalPlanPath=originalPlanPath;
        end

        % Button pushed function: OutputDirectoryButton
        function OutputDirectoryButtonPushed(app, event)
            global KIM
            Copy_dir = uigetdir('C:\');     
            KIM.Copy_dir=Copy_dir;
        end

        % Button pushed function: AnonymiseFractiondataButton
        function AnonymiseFractiondataButtonPushed(app, event)
            global KIM;
            %originalPlanPath = KIM.originalPlanPath
            Copy_dir = KIM.Copy_dir
            value = KIM.value
            valuename = KIM.valuename
            filedeidentify_batch(Copy_dir, value, valuename)
            
            
        end

        % Value changed function: NametoreplaceEditField
        function NametoreplaceEditFieldValueChanged(app, event)
            global KIM;
            value = app.NametoreplaceEditField.Value;
            KIM.value= value
        end

        % Value changed function: PatientnameEditField
        function PatientnameEditFieldValueChanged(app, event)
            global KIM
            valuename = app.PatientnameEditField.Value;
            KIM.valuename = valuename
        end

        % Button pushed function: AnonymiseCBCTdataButton
        function AnonymiseCBCTdataButtonPushed(app, event)
            global KIM;
            %originalPlanPath = KIM.originalPlanPath
            Copy_dir = KIM.Copy_dir
            value = KIM.value
            valuename = KIM.valuename
            filedeidentify_batch_CBCT(Copy_dir, value, valuename)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OutputDirectoryButton = uibutton(app.UIFigure, 'push');
            app.OutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirectoryButtonPushed, true);
            app.OutputDirectoryButton.Text = 'Output Directory';
            app.AnonymiseFractiondataButton = uibutton(app.UIFigure, 'push');
            app.AnonymiseFractiondataButton.ButtonPushedFcn = createCallbackFcn(app, @AnonymiseFractiondataButtonPushed, true);
            app.AnonymiseFractiondataButton.Text = 'Anonymise Fraction data';
            app.FolderinwhichthedatahavebeencopiedLabel = uilabel(app.UIFigure);
            app.FolderinwhichthedatahavebeencopiedLabel.Text = '"Folder in which the data have been copied" ';
            app.NametoreplaceEditFieldLabel = uilabel(app.UIFigure);
            app.NametoreplaceEditFieldLabel.Text = 'Name to replace';
            app.NametoreplaceEditField = uieditfield(app.UIFigure, 'text');
            app.NametoreplaceEditField.ValueChangedFcn = createCallbackFcn(app, @NametoreplaceEditFieldValueChanged, true);
            app.PatientnameEditFieldLabel = uilabel(app.UIFigure);
            app.PatientnameEditFieldLabel.Text = 'Patient name';
            app.PatientnameEditField = uieditfield(app.UIFigure, 'text');
            app.PatientnameEditField.ValueChangedFcn = createCallbackFcn(app, @PatientnameEditFieldValueChanged, true);
            app.FormatFirstnameLastnameLabel = uilabel(app.UIFigure);
            app.FormatFirstnameLastnameLabel.Text = 'Format: Firstname,Lastname';
            app.FormatLARK_WM_PAT1Label = uilabel(app.UIFigure);
            app.FormatLARK_WM_PAT1Label.Text = 'Format: LARK_WM_PAT1';
            app.AnonymiseCBCTdataButton = uibutton(app.UIFigure, 'push');
            app.AnonymiseCBCTdataButton.ButtonPushedFcn = createCallbackFcn(app, @AnonymiseCBCTdataButtonPushed, true);
            app.AnonymiseCBCTdataButton.Text = 'Anonymise CBCT data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = deindentifier

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end